classdef BSmodel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BuildingStockModelUIFigure      matlab.ui.Figure
        FileMenu                        matlab.ui.container.Menu
        SaveasMenu                      matlab.ui.container.Menu
        QuitMenu                        matlab.ui.container.Menu
        SimulationMenu                  matlab.ui.container.Menu
        PlotMenu                        matlab.ui.container.Menu
        PopulationMenu                  matlab.ui.container.Menu
        SurfaceAreaMenu                 matlab.ui.container.Menu
        RunMenu                         matlab.ui.container.Menu
        TabGroup                        matlab.ui.container.TabGroup
        SetupTab                        matlab.ui.container.Tab
        TimePanel                       matlab.ui.container.Panel
        StartdateDatePickerLabel        matlab.ui.control.Label
        StartdateDatePicker             matlab.ui.control.DatePicker
        EnddateDatePickerLabel          matlab.ui.control.Label
        EnddateDatePicker               matlab.ui.control.DatePicker
        BuildingsmeasurmentsandspecificationsPanel  matlab.ui.container.Panel
        BrowseButton                    matlab.ui.control.Button
        BuildingStockMatrixLabel        matlab.ui.control.Label
        BSMatrix_Field                  matlab.ui.control.EditField
        WeatherFileTab                  matlab.ui.container.Tab
        ListBox                         matlab.ui.control.ListBox
        AddButton                       matlab.ui.control.Button
        DeleteselectedButton            matlab.ui.control.Button
        RenovationTab                   matlab.ui.container.Tab
        SetuptherenovationratiotargetsandlevelsLabel  matlab.ui.control.Label
        RenovationisenabledCheckBox     matlab.ui.control.CheckBox
        Label_4                         matlab.ui.control.Label
        AnnualrenovationrateforthenonresidentialbuildingsLabel  matlab.ui.control.Label
        AnnualrenovationrateCurrentEditField  matlab.ui.control.EditField
        yearsLabel                      matlab.ui.control.Label
        GraceperiodfortherenovationanddemolitionEditFieldLabel  matlab.ui.control.Label
        GraceperiodfortherenovationanddemolitionEditField  matlab.ui.control.EditField
        Label_6                         matlab.ui.control.Label
        EnergyefficiencyimprovementafterrenovationEditFieldLabel  matlab.ui.control.Label
        EnergyefficiencyimprovementafterrenovationEditField  matlab.ui.control.EditField
        yearsLabel_4                    matlab.ui.control.Label
        EnergyefficiencyimprovementafterrenovationEditFieldLabel_2  matlab.ui.control.Label
        MeanValueofnormaldistributionEditField  matlab.ui.control.EditField
        Annualrenovationrate2030EditField  matlab.ui.control.EditField
        Annualrenovationrate2050EditField  matlab.ui.control.EditField
        Label_28                        matlab.ui.control.Label
        Label_29                        matlab.ui.control.Label
        CurrentLabel                    matlab.ui.control.Label
        Label_30                        matlab.ui.control.Label
        Label_31                        matlab.ui.control.Label
        DemolitionTab                   matlab.ui.container.Tab
        SetupthedemolitionratiotargetsandlevelsLabel  matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        yearsLabel_2                    matlab.ui.control.Label
        yearsLabel_3                    matlab.ui.control.Label
        ScaleparameterorcharacteristiclifeEditFieldLabel  matlab.ui.control.Label
        ScaleparameterorcharacteristiclifeEditField  matlab.ui.control.EditField
        ShapeparamterorslopeEditFieldLabel  matlab.ui.control.Label
        ShapeparamterorslopeEditField   matlab.ui.control.EditField
        LocationparameterorfailurefreelifeEditFieldLabel  matlab.ui.control.Label
        LocationparameterorfailurefreelifeEditField  matlab.ui.control.EditField
        Label_8                         matlab.ui.control.Label
        AnnualdemolitionrateforNRBEditFieldLabel  matlab.ui.control.Label
        AnnualdemolitionrateforNRBEditField  matlab.ui.control.EditField
        ConstructionTab                 matlab.ui.container.Tab
        SetuptheconstructionratiotargetsandlevelsLabel  matlab.ui.control.Label
        Label_9                         matlab.ui.control.Label
        Label_10                        matlab.ui.control.Label
        ScaleparameterorcharacteristiclifeEditField_2Label  matlab.ui.control.Label
        ParameterforcorrelationEditField  matlab.ui.control.EditField
        AnnualvolumeofnewNRBcomparedwithnewRBLabel  matlab.ui.control.Label
        AnnualvolumeofnewNRBEditField   matlab.ui.control.EditField
        EnergyEfficiencyimprovementfornewbuildingsbuiltbefore2020Label  matlab.ui.control.Label
        EnergyEfficiencybefore2020EditField  matlab.ui.control.EditField
        EnergyEfficiencyimprovementfornewbuildingsbuiltafter2020Label_2  matlab.ui.control.Label
        EnergyEfficiencyafter2020EditField  matlab.ui.control.EditField
        AllowederrorforcalculationofthevolumeofnewbuildingsLabel  matlab.ui.control.Label
        AllowedErrorforcalculationEditField  matlab.ui.control.EditField
        Label_11                        matlab.ui.control.Label
        Label_12                        matlab.ui.control.Label
        Label_13                        matlab.ui.control.Label
        Label_14                        matlab.ui.control.Label
        DetachedhousesamountEditField   matlab.ui.control.EditField
        RowhousesamountEditField        matlab.ui.control.EditField
        ApartmentsbuildingsamountEditField  matlab.ui.control.EditField
        DetachedhousesLabel             matlab.ui.control.Label
        RowhousesLabel                  matlab.ui.control.Label
        ApartmentsbuildingsLabel        matlab.ui.control.Label
        Label_15                        matlab.ui.control.Label
        DemographicTab                  matlab.ui.container.Tab
        PopulationvariationLabel        matlab.ui.control.Label
        TabGroup2                       matlab.ui.container.TabGroup
        SetbydateTab                    matlab.ui.container.Tab
        Population2013EditField         matlab.ui.control.EditField
        Population2030EditField         matlab.ui.control.EditField
        Population2050EditField         matlab.ui.control.EditField
        Label_22                        matlab.ui.control.Label
        Label_23                        matlab.ui.control.Label
        Label_24                        matlab.ui.control.Label
        PopulationSensitivityEditField  matlab.ui.control.EditField
        SensitivityLabel_3              matlab.ui.control.Label
        BrowseFileTab                   matlab.ui.container.Tab
        BrowseButton_Population         matlab.ui.control.Button
        TextArea_Population             matlab.ui.control.TextArea
        TabGroup2_2                     matlab.ui.container.TabGroup
        SetbydateTab_2                  matlab.ui.container.Tab
        SurfaceArea2013EditField        matlab.ui.control.EditField
        SurfaceArea2030EditField        matlab.ui.control.EditField
        SurfaceArea2050EditField        matlab.ui.control.EditField
        Label_25                        matlab.ui.control.Label
        Label_26                        matlab.ui.control.Label
        Label_27                        matlab.ui.control.Label
        SensitivitySurfaceAreaEditField  matlab.ui.control.EditField
        SensitivityLabel_4              matlab.ui.control.Label
        BrowseFileTab_2                 matlab.ui.container.Tab
        BrowseButton_3                  matlab.ui.control.Button
        TextArea_2                      matlab.ui.control.TextArea
        SurfaceareaLabel                matlab.ui.control.Label
        InterpolationmethodDropDownLabel  matlab.ui.control.Label
        InterpolationmethodPopulationDropDown  matlab.ui.control.DropDown
        InterpolationmethodDropDown_2Label  matlab.ui.control.Label
        InterpolationmethodSurfaceAreaDropDown  matlab.ui.control.DropDown
        PlotPopulation                  matlab.ui.control.CheckBox
        PlotSurfaceArea                 matlab.ui.control.CheckBox
        SetupthedemographicchangeLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        BS_matrix   = 0 ;% this is the variable to store the loaded data from the BSMatrix
        Pop = BSmodel.func2 ;
        Area = BSmodel.func3 ;
        PopulationFiledata = 0 ;
        SurfaceArea = 0 ;
    end

    methods(Static)
        function results = func2
            data.Population2013EditField.Value = '141305' ;
            data.Population2030EditField.Value = '151359' ;
            data.Population2050EditField.Value = '149778' ;
            data.PopulationSensitivityEditField.Value = '2' ;
            data.PlotPopulation.Value = false ;
            data.InterpolationmethodPopulationDropDown.Value = 'spline' ;
            results = Population('', data)   ;
        end
        function results = func3
            data.SurfaceArea2013EditField.Value = '38' ;
            data.SurfaceArea2030EditField.Value = '40.4' ;
            data.SurfaceArea2050EditField.Value = '44.2' ;
            data.SensitivitySurfaceAreaEditField.Value = '2' ;
            data.PlotSurfaceArea.Value = false ;
            data.InterpolationmethodSurfaceAreaDropDown.Value = 'spline' ;
            results = Areafunc('', data)   ;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [fileOT,pathOT]=uigetfile( ...
                                        {'*.mat;*.csv',...
                                            'MATLAB Files (*.mat,*.csv)';
                                           '*.*',  'All Files (*.*)'}, ...
                                           'Select a File');
            app.BSMatrix_Field.Value = [pathOT fileOT] ;
            if fileOT == 0 %app.BaselineTemperaturefilelocationEditField == 0
                app.BSMatrix_Field.Value = '';
                return
            end
            [~,~,ext] = fileparts(app.BSMatrix_Field.Value ) ;
            % Validate the input file. If this is a .csv, load it to see if
            % it has the right structure. If it is a MatLab file, then load
            % it to check if it has the right number of columns.

            switch ext
                case '.csv'
                    checkvar = readtable(app.BSMatrix_Field.Value) ;
                    for ivar = 1:length(checkvar.Properties.VariableNames)
                        ivarname = checkvar.Properties.VariableNames{ivar} ;
                        if ~isa(checkvar.(ivarname), 'double')
                            errordlg('All variable should be of type double') 
                            app.BSMatrix_Field.Value = '';
                            return
                        end
                    end
                    checkvar = table2array(checkvar);
                case '.mat'
                    checkvar = load(app.BSMatrix_Field.Value) ;
                    ivarname = fieldnames(checkvar)           ;
                    checkvar = checkvar.(ivarname{1}) ;
                    
                    if ~isa(checkvar, 'double')
                        if isa(checkvar, 'table') 
                            for ivar = 1:length(checkvar.Properties.VariableNames)
                                ivarname = checkvar.Properties.VariableNames{ivar} ;
                                if ~isa(checkvar.(ivarname), 'double')
                                    errordlg('All variable should be of type double') 
                                    app.BSMatrix_Field.Value = '';
                                    return
                                end
                            end
                            checkvar = table2array(checkvar);
                        else
                            errordlg('Variable should be a table or a double array matrix') 
                            app.BSMatrix_Field.Value = '';
                            return
                        end
                    end
                    
                    for ivar = 1:size(checkvar, 2)
                        ivarname = checkvar(:,ivar) ;
                        if ~isa(ivarname, 'double')
                            errordlg('All variable should be of type double') 
                            app.BSMatrix_Field.Value = '';
                            return
                        end
                    end
                otherwise
                    errordlg('File format not supported') 
                    app.BSMatrix_Field.Value = '';
                    return
            end
            app.BS_matrix = checkvar;
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            [fileOT,pathOT]=uigetfile( ...
                                        {'*.mat;*.epw',...
                                            'MATLAB Files (*.mat,*.epw)'}, ...
                                           'Select a File',...
                                           'Multiselect', 'on');

            if isa(fileOT, 'cell') 
                filepath = cell(1, length(fileOT)) ;
                for icell = 1:length(fileOT)
                    filename = fileOT{icell} ;
                    if isempty(filename)
                        return;
                    end
                    filepath{icell} = [pathOT fileOT{icell}] ;
                    checkvar = Checkinputfile(filepath{icell}) ;
                end
            elseif isa(fileOT, 'double') 
                if fileOT == 0 %app.BaselineTemperaturefilelocationEditField == 0
                    return
                end
            else
                filepath = [pathOT fileOT] ;
                checkvar = Checkinputfile(filepath) ;
            end
            
            oriList             = app.ListBox.Items ;
            if isa(filepath, 'cell') 
                for icell = 1:length(filepath)
                    filename = filepath{icell} ;
                    if isempty(filename)
                        return;
                    end
                    oriList{end+1}      = filename ;
                end
            else
                oriList{end+1}      = filepath          ;
            end
                
            app.ListBox.Items   = oriList         ;
        end

        % Button pushed function: DeleteselectedButton
        function DeleteselectedButtonPushed(app, event)
            [~,idx] = ismember(app.ListBox.Value,app.ListBox.Items);
            app.ListBox.Items(idx) = [];
        end

        % Callback function
        function BrowseButton_2Pushed(app, event)
            [fileOT,pathOT]=uigetfile( ...
                                        {'*.mat;*.csv',...
                                            'MATLAB Files (*.mat,*.csv)';
                                           '*.*',  'All Files (*.*)'}, ...
                                           'Select a File');
            
            if fileOT == 0 %app.BaselineTemperaturefilelocationEditField == 0
                app.BSMatrix_Field.Value = '';
                return
            end
            app.BSMatrix_Field.Value = [pathOT fileOT] ;
            [~,~,ext] = fileparts(app.BSMatrix_Field.Value ) ;
            % Validate the input file. If this is a .csv, load it to see if
            % it has the right structure. If it is a MatLab file, then load
            % it to check if it has the right number of columns.
            switch ext
                case '.csv'
                    checkvar = readtable(app.BSMatrix_Field.Value) ;
                    for ivar = 1:length(checkvar.Properties.VariableNames)
                        ivarname = checkvar.Properties.VariableNames{ivar} ;
                        if ~isa(checkvar.(ivarname), 'double')
                            errordlg('All variable should be of type double') 
                            app.BSMatrix_Field.Value = '';
                            return
                        end
                    end
                    checkvar = table2array(checkvar);
                case '.mat'
                    checkvar = load(app.BSMatrix_Field.Value) ;
                    ivarname = fieldnames(checkvar)           ;
                    checkvar = checkvar.(ivarname{1}) ;
                    
                    if ~isa(checkvar, 'double')
                        if isa(checkvar, 'table') 
                            for ivar = 1:length(checkvar.Properties.VariableNames)
                                ivarname = checkvar.Properties.VariableNames{ivar} ;
                                if ~isa(checkvar.(ivarname), 'double')
                                    errordlg('All variable should be of type double') 
                                    app.BSMatrix_Field.Value = '';
                                    return
                                end
                            end
                            checkvar = table2array(checkvar);
                        else
                            errordlg('Variable should be a table or a double array matrix') 
                            app.BSMatrix_Field.Value = '';
                            return
                        end
                    end
                    
                    for ivar = 1:size(checkvar, 2)
                        ivarname = checkvar(:,ivar) ;
                        if ~isa(ivarname, 'double')
                            errordlg('All variable should be of type double') 
                            app.BSMatrix_Field.Value = '';
                            return
                        end
                    end
                otherwise
                    errordlg('File format not supported') 
                    app.BSMatrix_Field.Value = '';
                    return
            end
        end

        % Menu selected function: RunMenu
        function RunMenuSelected(app, event)
            % Build the PARAM variable
                PARAMETER_matrix(1)    = round(years(app.EnddateDatePicker.Value - app.StartdateDatePicker.Value)) ;
                PARAMETER_matrix(2)    = double(app.RenovationisenabledCheckBox.Value) ;
                PARAMETER_matrix(3)    = str2double(app.MeanValueofnormaldistributionEditField.Value) ; %mean value of normal distribution for modelling RB renovation (years)
                PARAMETER_matrix(4)    = str2double(app.AnnualrenovationrateCurrentEditField.Value) ;
                PARAMETER_matrix(5)    = str2double(app.GraceperiodfortherenovationanddemolitionEditField.Value) ; % grace period for renovation and demolition (years)
                PARAMETER_matrix(6)    = str2double(app.EnergyefficiencyimprovementafterrenovationEditField.Value) ; % energy efficiency improvement after renovation (%)
                PARAMETER_matrix(7)    = str2double(app.ScaleparameterorcharacteristiclifeEditField.Value) ; % parameters of the Weibull distribution for modelling RB demolition
                PARAMETER_matrix(8)    = str2double(app.ShapeparamterorslopeEditField.Value) ; % parameters of the Weibull distribution for modelling RB demolition
                PARAMETER_matrix(9)    = str2double(app.LocationparameterorfailurefreelifeEditField.Value) ; % parameters of the Weibull distribution for modelling RB demolition
                PARAMETER_matrix(10)   = str2double(app.AnnualdemolitionrateforNRBEditField.Value); % annual demolition rate for NRB (%)
                PARAMETER_matrix(11)   = str2double(app.ParameterforcorrelationEditField.Value); % parameter for correlation between RB volumeand floor area
                PARAMETER_matrix(12)   = str2double(app.DetachedhousesamountEditField.Value) ; % share of the volume of detached, row and apartment buildings among new RB (%)
                PARAMETER_matrix(13)   = str2double(app.RowhousesamountEditField.Value); % share of the volume of detached, row and apartment buildings among new RB (%)
                PARAMETER_matrix(14)   = str2double(app.ApartmentsbuildingsamountEditField.Value); % share of the volume of detached, row and apartment buildings among new RB (%)
                PARAMETER_matrix(15)   = str2double(app.AllowedErrorforcalculationEditField.Value); % allowed error for calculation of the volume of new buildings (%)
                PARAMETER_matrix(16)   = str2double(app.EnergyEfficiencybefore2020EditField.Value); % energy efficiency improvement for newbuildings built before 2020
                PARAMETER_matrix(17)   = str2double(app.EnergyEfficiencyafter2020EditField.Value); % energy efficiency improvement for newbuildings built after 2020
                PARAMETER_matrix(18)   = str2double(app.AnnualvolumeofnewNRBEditField.Value) ; % annual volume of new NRB compared with new RB (%)
                
                
                RENO_matrix(2) = str2double(app.Annualrenovationrate2030EditField.Value) ;
                RENO_matrix(3) = str2double(app.Annualrenovationrate2050EditField.Value) ;
                
                if and(PARAMETER_matrix(2) == RENO_matrix(2),PARAMETER_matrix(2) == RENO_matrix(3))
                    RENO_matrix(1) = 0 ;
                else
                    RENO_matrix(1) = 1 ;
                end
                if isempty(app.Area) || isempty(app.Pop)
                    errordlg('Missing data, go through the population and the area values')
                    return;
                end
                if app.BS_matrix == 0
                    errordlg('BS_matrix missing. Add the Building stock matrix')
                    return;
                end
                [BS_matrix_new,BS_matrix_new30,RBs,NRBs,rates,vol] = SEN2050_BSModel_v7(app.BS_matrix,PARAMETER_matrix,app.Pop,app.Area,RENO_matrix) ;
                assignin('base','BS_matrix_new',BS_matrix_new) ;
                assignin('base','BS_matrix_new30',BS_matrix_new30) ;
                assignin('base','RBs',RBs) ;
                assignin('base','NRBs', NRBs) ;
                assignin('base','rates', rates) ;
                assignin('base','vol', vol);
        end

        % Value changed function: Population2013EditField, 
        % Population2030EditField, Population2050EditField, 
        % PopulationSensitivityEditField
        function Population2013EditFieldValueChanged(app, event)
            value   = app.Population2013EditField.Value;
            app.Pop = Population(event, app)   ;
        end

        % Value changing function: 
        % AllowedErrorforcalculationEditField, 
        % AnnualdemolitionrateforNRBEditField, 
        % AnnualvolumeofnewNRBEditField, 
        % ApartmentsbuildingsamountEditField, 
        % DetachedhousesamountEditField, 
        % EnergyEfficiencyafter2020EditField, 
        % EnergyEfficiencybefore2020EditField, 
        % LocationparameterorfailurefreelifeEditField, 
        % Population2013EditField, Population2030EditField, 
        % Population2050EditField, PopulationSensitivityEditField, 
        % RowhousesamountEditField, 
        % ScaleparameterorcharacteristiclifeEditField, 
        % SensitivitySurfaceAreaEditField, 
        % ShapeparamterorslopeEditField, SurfaceArea2013EditField, 
        % SurfaceArea2030EditField, SurfaceArea2050EditField
        function Population2013EditFieldValueChanging(app, event)
            changingValue   = str2double(event.Value)       ;  
            if ~isnumeric(changingValue) || isnan(changingValue)
                errordlg('Only numeric values are accepted','Inpur Error')
            end
        end

        % Value changed function: SensitivitySurfaceAreaEditField, 
        % SurfaceArea2013EditField, SurfaceArea2030EditField, 
        % SurfaceArea2050EditField
        function SurfaceArea2013EditFieldValueChanged(app, event)
            value = app.SurfaceArea2013EditField.Value;
            app.Area = Areafunc(event, app)   ;
        end

        % Button pushed function: BrowseButton_Population
        function BrowseButton_PopulationPushed(app, event)
            [fileOT,pathOT]=uigetfile( ...
                                        {'*.mat;*.csv',...
                                            'MATLAB Files (*.mat,*.csv)';
                                           '*.*',  'All Files (*.*)'}, ...
                                           'Select a File');
            app.TextArea_Population.Value = [pathOT fileOT] ;
            if fileOT == 0 %app.BaselineTemperaturefilelocationEditField == 0
                app.TextArea_Population.Value = '';
                return
            end
            [~,~,ext] = fileparts(app.TextArea_Population.Value ) ;
            % Validate the input file. If this is a .csv, load it to see if
            % it has the right structure. If it is a MatLab file, then load
            % it to check if it has the right number of columns.

            switch ext
                case '.csv'
                    checkvar = readtable(app.TextArea_Population.Value{1}) ;
                    for ivar = 1:length(checkvar.Properties.VariableNames)
                        ivarname = checkvar.Properties.VariableNames{ivar} ;
                        if ~isa(checkvar.(ivarname), 'double')
                            errordlg('All variable should be of type double') 
                            app.TextArea_Population.Value = '';
                            return
                        end
                    end
                    checkvar = table2array(checkvar);
                case '.mat'
                    checkvar = load(app.TextArea_Population.Value) ;
                    ivarname = fieldnames(checkvar)           ;
                    checkvar = checkvar.(ivarname{1}) ;
                    
                    if ~isa(checkvar, 'double')
                        if isa(checkvar, 'table') 
                            for ivar = 1:length(checkvar.Properties.VariableNames)
                                ivarname = checkvar.Properties.VariableNames{ivar} ;
                                if ~isa(checkvar.(ivarname), 'double')
                                    errordlg('All variable should be of type double') 
                                    app.TextArea_Population.Value = '';
                                    return
                                end
                            end
                            checkvar = table2array(checkvar);
                        else
                            errordlg('Variable should be a table or a double array matrix') 
                            app.TextArea_Population.Value = '';
                            return
                        end
                    end
                    
                    for ivar = 1:size(checkvar, 2)
                        ivarname = checkvar(:,ivar) ;
                        if ~isa(ivarname, 'double')
                            errordlg('All variable should be of type double') 
                            app.TextArea_Population.Value = '';
                            return
                        end
                    end
                otherwise
                    errordlg('File format not supported') 
                    app.TextArea_Population.Value = '';
                    return
            end
            app.PopulationFiledata = checkvar;
        end

        % Menu selected function: PopulationMenu
        function PopulationMenuSelected(app, event)
           
            switch app.TabGroup2.SelectedTab.Title
                case 'Set by date'
                    value   = app.Population2013EditField.Value;
                case 'Browse File'
                    value   = app.PopulationFiledata ;
                otherwise
                    
            end
            app.Pop = Population(value, app)   ;
        end

        % Menu selected function: SurfaceAreaMenu
        function SurfaceAreaMenuSelected(app, event)
            switch app.TabGroup2_2.SelectedTab.Title
                case 'Set by date'
                    value   = app.SurfaceArea2030EditField.Value;
                case 'Browse File'
                    value   = app.PopulationFiledata ;
                otherwise
                    
            end
            app.Pop = Population(value, app)   ;
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            [fileOT,pathOT]=uigetfile( ...
                                        {'*.mat;*.csv',...
                                            'MATLAB Files (*.mat,*.csv)';
                                           '*.*',  'All Files (*.*)'}, ...
                                           'Select a File');
            app.TextArea_2.Value = [pathOT fileOT] ;
            if fileOT == 0 %app.BaselineTemperaturefilelocationEditField == 0
                app.TextArea_2.Value = '';
                return
            end
            [~,~,ext] = fileparts(app.TextArea_2.Value ) ;
            % Validate the input file. If this is a .csv, load it to see if
            % it has the right structure. If it is a MatLab file, then load
            % it to check if it has the right number of columns.

            switch ext
                case '.csv'
                    checkvar = readtable(app.TextArea_2.Value{1}) ;
                    for ivar = 1:length(checkvar.Properties.VariableNames)
                        ivarname = checkvar.Properties.VariableNames{ivar} ;
                        if ~isa(checkvar.(ivarname), 'double')
                            errordlg('All variable should be of type double') 
                            app.TextArea_2.Value = '';
                            return
                        end
                    end
                    checkvar = table2array(checkvar);
                case '.mat'
                    checkvar = load(app.TextArea_2.Value) ;
                    ivarname = fieldnames(checkvar)           ;
                    checkvar = checkvar.(ivarname{1}) ;
                    
                    if ~isa(checkvar, 'double')
                        if isa(checkvar, 'table') 
                            for ivar = 1:length(checkvar.Properties.VariableNames)
                                ivarname = checkvar.Properties.VariableNames{ivar} ;
                                if ~isa(checkvar.(ivarname), 'double')
                                    errordlg('All variable should be of type double') 
                                    app.TextArea_2.Value = '';
                                    return
                                end
                            end
                            checkvar = table2array(checkvar);
                        else
                            errordlg('Variable should be a table or a double array matrix') 
                            app.TextArea_2.Value = '';
                            return
                        end
                    end
                    
                    for ivar = 1:size(checkvar, 2)
                        ivarname = checkvar(:,ivar) ;
                        if ~isa(ivarname, 'double')
                            errordlg('All variable should be of type double') 
                            app.TextArea_2.Value = '';
                            return
                        end
                    end
                otherwise
                    errordlg('File format not supported') 
                    app.TextArea_2.Value = '';
                    return
            end
            app.SurfaceArea = checkvar;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BuildingStockModelUIFigure = uifigure('Visible', 'off');
            app.BuildingStockModelUIFigure.Name = 'Building Stock Model';
            app.BuildingStockModelUIFigure.Icon = 'icon.png';
            app.FileMenu = uimenu(app.BuildingStockModelUIFigure);
            app.FileMenu.Text = 'File';
            app.SaveasMenu = uimenu(app.FileMenu);
            app.SaveasMenu.Text = 'Save as...';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.Text = 'Quit';
            app.SimulationMenu = uimenu(app.BuildingStockModelUIFigure);
            app.SimulationMenu.Text = 'Simulation';
            app.PlotMenu = uimenu(app.SimulationMenu);
            app.PlotMenu.Text = 'Plot';
            app.PopulationMenu = uimenu(app.PlotMenu);
            app.PopulationMenu.MenuSelectedFcn = createCallbackFcn(app, @PopulationMenuSelected, true);
            app.PopulationMenu.Text = 'Population';
            app.SurfaceAreaMenu = uimenu(app.PlotMenu);
            app.SurfaceAreaMenu.MenuSelectedFcn = createCallbackFcn(app, @SurfaceAreaMenuSelected, true);
            app.SurfaceAreaMenu.Text = 'Surface Area';
            app.RunMenu = uimenu(app.SimulationMenu);
            app.RunMenu.MenuSelectedFcn = createCallbackFcn(app, @RunMenuSelected, true);
            app.RunMenu.Text = 'Run';
            app.TabGroup = uitabgroup(app.BuildingStockModelUIFigure);
            app.SetupTab = uitab(app.TabGroup);
            app.SetupTab.Title = 'Setup';
            app.TimePanel = uipanel(app.SetupTab);
            app.TimePanel.Title = 'Time';
            app.StartdateDatePickerLabel = uilabel(app.TimePanel);
            app.StartdateDatePickerLabel.Text = 'Start date';
            app.StartdateDatePicker = uidatepicker(app.TimePanel);
            app.StartdateDatePicker.Value = datetime([2013 1 1]);
            app.EnddateDatePickerLabel = uilabel(app.TimePanel);
            app.EnddateDatePickerLabel.Text = 'End date';
            app.EnddateDatePicker = uidatepicker(app.TimePanel);
            app.EnddateDatePicker.Value = datetime([2050 12 31]);
            app.BuildingsmeasurmentsandspecificationsPanel = uipanel(app.SetupTab);
            app.BuildingsmeasurmentsandspecificationsPanel.Title = 'Buildings measurments and specifications';
            app.BrowseButton = uibutton(app.BuildingsmeasurmentsandspecificationsPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse...';
            app.BuildingStockMatrixLabel = uilabel(app.BuildingsmeasurmentsandspecificationsPanel);
            app.BuildingStockMatrixLabel.Tooltip = {'BS_matrix contains data for all buildings included in the original data.'; 'First column contains ID number for the building from 1 to 3880 initially.'; '    %Second column contains building group number for the building:'; '    - 1 = detached house'; '    - 2 = row house'; '    - 3 = apartment building'; '    - 4 = non-residential building'; 'Third column contains number for contruction decade (original data) or year for the building:'; '    - 0  = unknown'; '    - 1  = before 1949'; '    - 2  = 1950-1959'; '    - 3  = 1960-1969'; '    - 4  = 1970-1979'; '    - 5  = 1980-1989'; '    - 6  = 1990-1999'; '    - 7  = 2000-2009'; '    - 8-43  = 2014-2049 '; 'Fourth column contains the volume (m3) of the building'; 'Fifth column contains the characteristic heat demand (kWh/m3) of the building.'; 'Sixth column contains the grace period (years) for renovation and demolition. If zero building can be renovated or demolished.'; 'Seventh column contains energy efficiency coefficient. Initially 1.'; 'Columns 8-58 contain information for the heat demand model.'};
            app.BuildingStockMatrixLabel.Text = 'Building Stock Matrix';
            app.BSMatrix_Field = uieditfield(app.BuildingsmeasurmentsandspecificationsPanel, 'text');
            app.WeatherFileTab = uitab(app.TabGroup);
            app.WeatherFileTab.Title = 'Weather File';
            app.ListBox = uilistbox(app.WeatherFileTab);
            app.ListBox.Items = {};
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {};
            app.AddButton = uibutton(app.WeatherFileTab, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.DeleteselectedButton = uibutton(app.WeatherFileTab, 'push');
            app.DeleteselectedButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteselectedButtonPushed, true);
            app.DeleteselectedButton.Text = 'Delete selected';
            app.RenovationTab = uitab(app.TabGroup);
            app.RenovationTab.Title = 'Renovation';
            app.SetuptherenovationratiotargetsandlevelsLabel = uilabel(app.RenovationTab);
            app.SetuptherenovationratiotargetsandlevelsLabel.Text = 'Setup the renovation ratio, targets, and levels';
            app.RenovationisenabledCheckBox = uicheckbox(app.RenovationTab);
            app.RenovationisenabledCheckBox.Text = 'Renovation is enabled';
            app.RenovationisenabledCheckBox.Value = true;
            app.Label_4 = uilabel(app.RenovationTab);
            app.Label_4.Text = '[%]';
            app.AnnualrenovationrateforthenonresidentialbuildingsLabel = uilabel(app.RenovationTab);
            app.AnnualrenovationrateforthenonresidentialbuildingsLabel.Text = {'Annual renovation rate for the non-residential '; 'buildings'};
            app.AnnualrenovationrateCurrentEditField = uieditfield(app.RenovationTab, 'text');
            app.AnnualrenovationrateCurrentEditField.Value = '1';
            app.yearsLabel = uilabel(app.RenovationTab);
            app.yearsLabel.Text = '[years]';
            app.GraceperiodfortherenovationanddemolitionEditFieldLabel = uilabel(app.RenovationTab);
            app.GraceperiodfortherenovationanddemolitionEditFieldLabel.Text = 'Grace period for the renovation and demolition';
            app.GraceperiodfortherenovationanddemolitionEditField = uieditfield(app.RenovationTab, 'text');
            app.GraceperiodfortherenovationanddemolitionEditField.Value = '10';
            app.Label_6 = uilabel(app.RenovationTab);
            app.Label_6.Text = '[%]';
            app.EnergyefficiencyimprovementafterrenovationEditFieldLabel = uilabel(app.RenovationTab);
            app.EnergyefficiencyimprovementafterrenovationEditFieldLabel.Text = 'Energy efficiency improvement after renovation';
            app.EnergyefficiencyimprovementafterrenovationEditField = uieditfield(app.RenovationTab, 'text');
            app.EnergyefficiencyimprovementafterrenovationEditField.Value = '50';
            app.yearsLabel_4 = uilabel(app.RenovationTab);
            app.yearsLabel_4.Text = '[years]';
            app.EnergyefficiencyimprovementafterrenovationEditFieldLabel_2 = uilabel(app.RenovationTab);
            app.EnergyefficiencyimprovementafterrenovationEditFieldLabel_2.Text = {'Mean value of normal distribution for modelling '; 'RB renovation'};
            app.MeanValueofnormaldistributionEditField = uieditfield(app.RenovationTab, 'text');
            app.MeanValueofnormaldistributionEditField.Value = '53';
            app.Annualrenovationrate2030EditField = uieditfield(app.RenovationTab, 'text');
            app.Annualrenovationrate2030EditField.Value = '1.5';
            app.Annualrenovationrate2050EditField = uieditfield(app.RenovationTab, 'text');
            app.Annualrenovationrate2050EditField.Value = '3';
            app.Label_28 = uilabel(app.RenovationTab);
            app.Label_28.Text = '[%]';
            app.Label_29 = uilabel(app.RenovationTab);
            app.Label_29.Text = '[%]';
            app.CurrentLabel = uilabel(app.RenovationTab);
            app.CurrentLabel.Text = 'Current';
            app.Label_30 = uilabel(app.RenovationTab);
            app.Label_30.Text = '2030';
            app.Label_31 = uilabel(app.RenovationTab);
            app.Label_31.Text = '2050';
            app.DemolitionTab = uitab(app.TabGroup);
            app.DemolitionTab.Title = 'Demolition';
            app.SetupthedemolitionratiotargetsandlevelsLabel = uilabel(app.DemolitionTab);
            app.SetupthedemolitionratiotargetsandlevelsLabel.Text = 'Setup the demolition ratio, targets, and levels';
            app.Label_7 = uilabel(app.DemolitionTab);
            app.Label_7.Text = '[%]';
            app.yearsLabel_2 = uilabel(app.DemolitionTab);
            app.yearsLabel_2.Text = '[years]';
            app.yearsLabel_3 = uilabel(app.DemolitionTab);
            app.yearsLabel_3.Text = '[years]';
            app.ScaleparameterorcharacteristiclifeEditFieldLabel = uilabel(app.DemolitionTab);
            app.ScaleparameterorcharacteristiclifeEditFieldLabel.Text = 'Scale parameter, or characteristic life:';
            app.ScaleparameterorcharacteristiclifeEditField = uieditfield(app.DemolitionTab, 'text');
            app.ScaleparameterorcharacteristiclifeEditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.ScaleparameterorcharacteristiclifeEditField.Value = '1.09';
            app.ShapeparamterorslopeEditFieldLabel = uilabel(app.DemolitionTab);
            app.ShapeparamterorslopeEditFieldLabel.Text = 'Shape paramter (or slope)';
            app.ShapeparamterorslopeEditField = uieditfield(app.DemolitionTab, 'text');
            app.ShapeparamterorslopeEditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.ShapeparamterorslopeEditField.Value = '473.1';
            app.LocationparameterorfailurefreelifeEditFieldLabel = uilabel(app.DemolitionTab);
            app.LocationparameterorfailurefreelifeEditFieldLabel.Text = 'Location parameter (or failure free life)';
            app.LocationparameterorfailurefreelifeEditField = uieditfield(app.DemolitionTab, 'text');
            app.LocationparameterorfailurefreelifeEditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.LocationparameterorfailurefreelifeEditField.Value = '10';
            app.Label_8 = uilabel(app.DemolitionTab);
            app.Label_8.Text = '[%]';
            app.AnnualdemolitionrateforNRBEditFieldLabel = uilabel(app.DemolitionTab);
            app.AnnualdemolitionrateforNRBEditFieldLabel.Text = 'Annual demolition rate for NRB';
            app.AnnualdemolitionrateforNRBEditField = uieditfield(app.DemolitionTab, 'text');
            app.AnnualdemolitionrateforNRBEditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.AnnualdemolitionrateforNRBEditField.Value = '0.65';
            app.ConstructionTab = uitab(app.TabGroup);
            app.ConstructionTab.Title = 'Construction';
            app.SetuptheconstructionratiotargetsandlevelsLabel = uilabel(app.ConstructionTab);
            app.SetuptheconstructionratiotargetsandlevelsLabel.Text = 'Setup the construction ratio, targets, and levels';
            app.Label_9 = uilabel(app.ConstructionTab);
            app.Label_9.Text = '[%]';
            app.Label_10 = uilabel(app.ConstructionTab);
            app.Label_10.Text = {'Share of the volume of detached, row and apartment buildings among '; 'new residential buildings:'};
            app.ScaleparameterorcharacteristiclifeEditField_2Label = uilabel(app.ConstructionTab);
            app.ScaleparameterorcharacteristiclifeEditField_2Label.Text = {'Parameter for correlation between residential building '; 'volume and floor area:'};
            app.ParameterforcorrelationEditField = uieditfield(app.ConstructionTab, 'text');
            app.ParameterforcorrelationEditField.Value = '1.09';
            app.AnnualvolumeofnewNRBcomparedwithnewRBLabel = uilabel(app.ConstructionTab);
            app.AnnualvolumeofnewNRBcomparedwithnewRBLabel.Text = 'Annual volume of new NRB compared with new RB';
            app.AnnualvolumeofnewNRBEditField = uieditfield(app.ConstructionTab, 'text');
            app.AnnualvolumeofnewNRBEditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.AnnualvolumeofnewNRBEditField.Value = '150';
            app.EnergyEfficiencyimprovementfornewbuildingsbuiltbefore2020Label = uilabel(app.ConstructionTab);
            app.EnergyEfficiencyimprovementfornewbuildingsbuiltbefore2020Label.Text = {'Energy Efficiency improvement for new buildings '; 'built before 2020'};
            app.EnergyEfficiencybefore2020EditField = uieditfield(app.ConstructionTab, 'text');
            app.EnergyEfficiencybefore2020EditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.EnergyEfficiencybefore2020EditField.Value = '30';
            app.EnergyEfficiencyimprovementfornewbuildingsbuiltafter2020Label_2 = uilabel(app.ConstructionTab);
            app.EnergyEfficiencyimprovementfornewbuildingsbuiltafter2020Label_2.Text = {'Energy Efficiency improvement for new buildings '; 'built after 2020'};
            app.EnergyEfficiencyafter2020EditField = uieditfield(app.ConstructionTab, 'text');
            app.EnergyEfficiencyafter2020EditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.EnergyEfficiencyafter2020EditField.Value = '50';
            app.AllowederrorforcalculationofthevolumeofnewbuildingsLabel = uilabel(app.ConstructionTab);
            app.AllowederrorforcalculationofthevolumeofnewbuildingsLabel.Text = {'Allowed error for calculation of the volume of new '; 'buildings'};
            app.AllowedErrorforcalculationEditField = uieditfield(app.ConstructionTab, 'text');
            app.AllowedErrorforcalculationEditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.AllowedErrorforcalculationEditField.Value = '0.1';
            app.Label_11 = uilabel(app.ConstructionTab);
            app.Label_11.Text = '[%]';
            app.Label_12 = uilabel(app.ConstructionTab);
            app.Label_12.Text = '[%]';
            app.Label_13 = uilabel(app.ConstructionTab);
            app.Label_13.Text = '[%]';
            app.Label_14 = uilabel(app.ConstructionTab);
            app.Label_14.Text = '[%]';
            app.DetachedhousesamountEditField = uieditfield(app.ConstructionTab, 'text');
            app.DetachedhousesamountEditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.DetachedhousesamountEditField.Value = '10.3';
            app.RowhousesamountEditField = uieditfield(app.ConstructionTab, 'text');
            app.RowhousesamountEditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.RowhousesamountEditField.Value = '11.7';
            app.ApartmentsbuildingsamountEditField = uieditfield(app.ConstructionTab, 'text');
            app.ApartmentsbuildingsamountEditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.ApartmentsbuildingsamountEditField.Value = '78';
            app.DetachedhousesLabel = uilabel(app.ConstructionTab);
            app.DetachedhousesLabel.Text = 'Detached houses';
            app.RowhousesLabel = uilabel(app.ConstructionTab);
            app.RowhousesLabel.Text = 'Row houses';
            app.ApartmentsbuildingsLabel = uilabel(app.ConstructionTab);
            app.ApartmentsbuildingsLabel.Text = 'Apartments buildings';
            app.Label_15 = uilabel(app.ConstructionTab);
            app.Label_15.Text = '[%]';
            app.DemographicTab = uitab(app.TabGroup);
            app.DemographicTab.Title = 'Demographic';
            app.PopulationvariationLabel = uilabel(app.DemographicTab);
            app.PopulationvariationLabel.Text = 'Population variation';
            app.TabGroup2 = uitabgroup(app.DemographicTab);
            app.SetbydateTab = uitab(app.TabGroup2);
            app.SetbydateTab.Title = 'Set by date';
            app.Population2013EditField = uieditfield(app.SetbydateTab, 'text');
            app.Population2013EditField.ValueChangedFcn = createCallbackFcn(app, @Population2013EditFieldValueChanged, true);
            app.Population2013EditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.Population2013EditField.Tag = 'pop2013';
            app.Population2013EditField.Value = '141305';
            app.Population2030EditField = uieditfield(app.SetbydateTab, 'text');
            app.Population2030EditField.ValueChangedFcn = createCallbackFcn(app, @Population2013EditFieldValueChanged, true);
            app.Population2030EditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.Population2030EditField.Tag = 'pop2030';
            app.Population2030EditField.Value = '151359';
            app.Population2050EditField = uieditfield(app.SetbydateTab, 'text');
            app.Population2050EditField.ValueChangedFcn = createCallbackFcn(app, @Population2013EditFieldValueChanged, true);
            app.Population2050EditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.Population2050EditField.Tag = 'pop2050';
            app.Population2050EditField.Value = '149778';
            app.Label_22 = uilabel(app.SetbydateTab);
            app.Label_22.Text = '2013';
            app.Label_23 = uilabel(app.SetbydateTab);
            app.Label_23.Text = '2030';
            app.Label_24 = uilabel(app.SetbydateTab);
            app.Label_24.Text = '2050';
            app.PopulationSensitivityEditField = uieditfield(app.SetbydateTab, 'text');
            app.PopulationSensitivityEditField.ValueChangedFcn = createCallbackFcn(app, @Population2013EditFieldValueChanged, true);
            app.PopulationSensitivityEditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.PopulationSensitivityEditField.Tag = 'popsens';
            app.PopulationSensitivityEditField.Value = '2';
            app.SensitivityLabel_3 = uilabel(app.SetbydateTab);
            app.SensitivityLabel_3.Text = 'Sensitivity +/- [%]';
            app.BrowseFileTab = uitab(app.TabGroup2);
            app.BrowseFileTab.Title = 'Browse File';
            app.BrowseButton_Population = uibutton(app.BrowseFileTab, 'push');
            app.BrowseButton_Population.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_PopulationPushed, true);
            app.BrowseButton_Population.Text = 'Browse ...';
            app.TextArea_Population = uitextarea(app.BrowseFileTab);
            app.TabGroup2_2 = uitabgroup(app.DemographicTab);
            app.SetbydateTab_2 = uitab(app.TabGroup2_2);
            app.SetbydateTab_2.Title = 'Set by date';
            app.SurfaceArea2013EditField = uieditfield(app.SetbydateTab_2, 'text');
            app.SurfaceArea2013EditField.ValueChangedFcn = createCallbackFcn(app, @SurfaceArea2013EditFieldValueChanged, true);
            app.SurfaceArea2013EditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.SurfaceArea2013EditField.Tag = 'area2013';
            app.SurfaceArea2013EditField.Value = '38';
            app.SurfaceArea2030EditField = uieditfield(app.SetbydateTab_2, 'text');
            app.SurfaceArea2030EditField.ValueChangedFcn = createCallbackFcn(app, @SurfaceArea2013EditFieldValueChanged, true);
            app.SurfaceArea2030EditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.SurfaceArea2030EditField.Tag = 'area2030';
            app.SurfaceArea2030EditField.Value = '40.4';
            app.SurfaceArea2050EditField = uieditfield(app.SetbydateTab_2, 'text');
            app.SurfaceArea2050EditField.ValueChangedFcn = createCallbackFcn(app, @SurfaceArea2013EditFieldValueChanged, true);
            app.SurfaceArea2050EditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.SurfaceArea2050EditField.Tag = 'area2050';
            app.SurfaceArea2050EditField.Value = '44.2';
            app.Label_25 = uilabel(app.SetbydateTab_2);
            app.Label_25.Text = '2013';
            app.Label_26 = uilabel(app.SetbydateTab_2);
            app.Label_26.Text = '2030';
            app.Label_27 = uilabel(app.SetbydateTab_2);
            app.Label_27.Text = '2050';
            app.SensitivitySurfaceAreaEditField = uieditfield(app.SetbydateTab_2, 'text');
            app.SensitivitySurfaceAreaEditField.ValueChangedFcn = createCallbackFcn(app, @SurfaceArea2013EditFieldValueChanged, true);
            app.SensitivitySurfaceAreaEditField.ValueChangingFcn = createCallbackFcn(app, @Population2013EditFieldValueChanging, true);
            app.SensitivitySurfaceAreaEditField.Tag = 'areasens';
            app.SensitivitySurfaceAreaEditField.Value = '2';
            app.SensitivityLabel_4 = uilabel(app.SetbydateTab_2);
            app.SensitivityLabel_4.Text = 'Sensitivity +/- [%]';
            app.BrowseFileTab_2 = uitab(app.TabGroup2_2);
            app.BrowseFileTab_2.Title = 'Browse File';
            app.BrowseButton_3 = uibutton(app.BrowseFileTab_2, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Text = 'Browse ...';
            app.TextArea_2 = uitextarea(app.BrowseFileTab_2);
            app.SurfaceareaLabel = uilabel(app.DemographicTab);
            app.SurfaceareaLabel.Text = 'Surface area';
            app.InterpolationmethodDropDownLabel = uilabel(app.DemographicTab);
            app.InterpolationmethodDropDownLabel.Text = 'Interpolation method';
            app.InterpolationmethodPopulationDropDown = uidropdown(app.DemographicTab);
            app.InterpolationmethodPopulationDropDown.Items = {'spline', 'linear', 'Gaussian'};
            app.InterpolationmethodPopulationDropDown.Value = 'spline';
            app.InterpolationmethodDropDown_2Label = uilabel(app.DemographicTab);
            app.InterpolationmethodDropDown_2Label.Text = 'Interpolation method';
            app.InterpolationmethodSurfaceAreaDropDown = uidropdown(app.DemographicTab);
            app.InterpolationmethodSurfaceAreaDropDown.Items = {'spline', 'linear', 'Gaussian'};
            app.InterpolationmethodSurfaceAreaDropDown.Value = 'spline';
            app.PlotPopulation = uicheckbox(app.DemographicTab);
            app.PlotPopulation.Text = 'Plot';
            app.PlotPopulation.Value = true;
            app.PlotSurfaceArea = uicheckbox(app.DemographicTab);
            app.PlotSurfaceArea.Text = 'Plot';
            app.PlotSurfaceArea.Value = true;
            app.SetupthedemographicchangeLabel = uilabel(app.DemographicTab);
            app.SetupthedemographicchangeLabel.Text = 'Setup the demographic change';
            % Show the figure after all components are created
            app.BuildingStockModelUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = BSmodel

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BuildingStockModelUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BuildingStockModelUIFigure)
        end
    end
end